classdef hw2_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EnterthetextfieldstringorthefilenameEditFieldLabel  matlab.ui.control.Label
        EnterthetextfieldstringorthefilenameEditField  matlab.ui.control.EditField
        InputsLabel                 matlab.ui.control.Label
        OutputsLabel                matlab.ui.control.Label
        SearchbuffersizeintegerEditFieldLabel  matlab.ui.control.Label
        SearchbuffersizeintegerEditField  matlab.ui.control.NumericEditField
        LookaheadbuffersizeintegerEditFieldLabel  matlab.ui.control.Label
        LookaheadbuffersizeintegerEditField  matlab.ui.control.NumericEditField
        BytesperinputcharacterEditFieldLabel  matlab.ui.control.Label
        BytesperinputcharactertextentropywithoutcompressionEditField  matlab.ui.control.NumericEditField
        CompresstheinputtextButton  matlab.ui.control.Button
        LZ77encodedoutputtripletsTextAreaLabel  matlab.ui.control.Label
        LZ77encodedoutputtripletsTextArea  matlab.ui.control.TextArea
        LZSSencodedoutputtuplesTextAreaLabel  matlab.ui.control.Label
        LZSSencodedoutputtuplesTextArea  matlab.ui.control.TextArea
        CompressedbytesperinputcharacterusingLZSSEditFieldLabel  matlab.ui.control.Label
        CompressedbytesperinputcharacterusingLZSSEditField  matlab.ui.control.NumericEditField
        CompressedbytesperinputcharacterusingLZ77EditField_2Label  matlab.ui.control.Label
        CompressedbytesperinputcharacterusingLZ77EditField_2  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        input_str;
        sbuffer_len;
        labuffer_len;
    end
    
    methods (Access = private)
        
        function [encoded_output] = lz77(app)
            input_length = length(app.input_str);
            if app.labuffer_len > input_length
                app.labuffer_len = input_length;
            end
            sbuffer = '';
            input_counter = app.labuffer_len;
            encoded_output = {};
            counter_output = 1;
            
            labuffer = app.input_str(1:app.labuffer_len);
            
            
            while isempty(labuffer) ~= true
                [prefix, dist] = app.longest_prefix(labuffer, sbuffer);
                if isempty(prefix)
                    d=0;
                    l=0;
                    c=labuffer(1);
                else
                    d = dist;
                    l = length(prefix);
                    c = labuffer(length(prefix)+1);
                end
                encoded_output{counter_output,1} = d;
                encoded_output{counter_output,2} = l;
                encoded_output{counter_output,3} = c;
                counter_output = counter_output + 1;
                %sbuffer(1:l+1) = [];
                s = labuffer(1:l+1);
                labuffer(1:l+1) = [];
                sbuffer = append(sbuffer, s);
                if length(sbuffer) > app.sbuffer_len
                    sbuffer(1:length(sbuffer)-app.sbuffer_len)=[];
                end
                labuffer_cap = app.labuffer_len-length(labuffer);
                if input_counter ~= input_length
                    if input_counter+1+labuffer_cap <= input_length
                        labuffer = append(labuffer, app.input_str(input_counter+1:input_counter+1+labuffer_cap-1));
                        input_counter = input_counter + labuffer_cap;
                    else
                        labuffer = append(labuffer, app.input_str(input_counter+1:end));
                        input_counter = input_length;
                    end
                end
            end
            
        end
        
        function [encoded_output] = lzss(app)
            input_length = length(app.input_str);
            if app.labuffer_len > input_length
                app.labuffer_len = input_length;
            end
            sbuffer = '';
            input_counter = app.labuffer_len;
            encoded_output = {};
            counter_output = 1;
            
            labuffer = app.input_str(1:app.labuffer_len);
            
            
            while isempty(labuffer) ~= true
                [prefix, dist] = app.longest_prefix(labuffer, sbuffer);
                if isempty(prefix)
                    d=0;
                    l=0;
                    c=labuffer(1);
                else
                    d = dist;
                    l = length(prefix);
                    c = labuffer(length(prefix)+1);
                end
                if d==0
                    encoded_output{counter_output,1} = 0;
                    encoded_output{counter_output,2} = c;
                else
                    encoded_output{counter_output,1} = d;
                    encoded_output{counter_output,2} = l;
                    encoded_output{counter_output,3} = c;
                end
                counter_output = counter_output + 1;
                %sbuffer(1:l+1) = [];
                s = labuffer(1:l+1);
                labuffer(1:l+1) = [];
                sbuffer = append(sbuffer, s);
                if length(sbuffer) > app.sbuffer_len
                    sbuffer(1:length(sbuffer)-app.sbuffer_len)=[];
                end
                labuffer_cap = app.labuffer_len-length(labuffer);
                if input_counter ~= input_length
                    if input_counter+1+labuffer_cap <= input_length
                        labuffer = append(labuffer, app.input_str(input_counter+1:input_counter+1+labuffer_cap-1));
                        input_counter = input_counter + labuffer_cap;
                    else
                        labuffer = append(labuffer, app.input_str(input_counter+1:end));
                        input_counter = input_length;
                    end
                end
            end
        end
        
        function [prefix, dist] = longest_prefix(~, labuffer, sbuffer)
            prefix = '';
            dist = 0;
            for i=length(labuffer)-1:-1:1
                s = labuffer(1:i);
                k = strfind(sbuffer, s);
                if isempty(k)
                    continue
                else
                    prefix = s;
                    dist = length(sbuffer)-k(end)+1;
                    break
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: 
        % EnterthetextfieldstringorthefilenameEditField
        function EnterthetextfieldstringorthefilenameEditFieldValueChanged(app, event)
            input_str_or_path = app.EnterthetextfieldstringorthefilenameEditField.Value;
            if isfile(input_str_or_path)
                tmp = fileread(input_str_or_path);
            else
                if isfile(append(input_str_or_path, '.txt'))
                    tmp = fileread(append(input_str_or_path, '.txt'));
                else
                    tmp = input_str_or_path;
                end
                
            end
            app.input_str = regexprep(tmp,'[^a-z^A-Z\s]','');
        end

        % Button pushed function: CompresstheinputtextButton
        function CompresstheinputtextButtonPushed(app, event)
            encoded_output_lz77 = app.lz77();
            encoded_output_lzss = app.lzss();
            alphabet = append('a':'z','A':'Z', ' ');
            n = length(alphabet);
            freqs = zeros(1,n);
            for i=1:length(app.input_str)
                ind = strfind(alphabet, app.input_str(i));
                freqs(ind) = freqs(ind)+1;
            end
            
            bits_no_comp = ceil(log2(n))*length(app.input_str);
            bits_lz77 = length(encoded_output_lzss)*(ceil(log2(app.sbuffer_len))+ceil(log2(app.labuffer_len))+ceil(log2(n)));
            bits_lzss = 0;
            for i=1:length(encoded_output_lzss)
                if encoded_output_lzss{i,1} == 0
                    bits_lzss = bits_lzss + 1 + ceil(log2(n));
                else
                    bits_lzss = bits_lzss + 1 + ceil(log2(app.sbuffer_len)) + ceil(log2(app.labuffer_len)) + ceil(log2(n));
                end
            end
            
            output_lz77 = '';
            for i=1:length(encoded_output_lz77)
                output_lz77 = append(output_lz77, '(', num2str(encoded_output_lz77{i,1}),...
                    ', ',num2str(encoded_output_lz77{i,2}),', ',num2str(encoded_output_lz77{i,3}),')');
                if i ~= length(encoded_output_lz77)
                    output_lz77 = append(output_lz77, ', ');
                end
            end
            
            output_lzss = '';
            for i=1:length(encoded_output_lzss)
                if encoded_output_lzss{i,1} ~= 0
                    output_lzss = append(output_lzss, '(', '1, ', num2str(encoded_output_lzss{i,1}),...
                        ', ',num2str(encoded_output_lzss{i,2}),', ',num2str(encoded_output_lzss{i,3}),')');
                else
                    output_lzss = append(output_lzss, '(', num2str(encoded_output_lzss{i,1}),...
                        ', ',num2str(encoded_output_lzss{i,2}),')');
                end
                if i ~= length(encoded_output_lzss)
                    output_lzss = append(output_lzss, ', ');
                end
            end
            non_compressed_bpc = bits_no_comp/8/length(app.input_str);
            lz77_bps = bits_lz77/8/length(app.input_str);
            lzss_bps = bits_lzss/8/length(app.input_str);
            app.BytesperinputcharactertextentropywithoutcompressionEditField.Value = non_compressed_bpc;
            app.CompressedbytesperinputcharacterusingLZ77EditField_2.Value = lz77_bps;
            app.CompressedbytesperinputcharacterusingLZSSEditField.Value = lzss_bps;
            app.LZ77encodedoutputtripletsTextArea.Value = output_lz77;
            app.LZSSencodedoutputtuplesTextArea.Value = output_lzss;
        end

        % Value changed function: SearchbuffersizeintegerEditField
        function SearchbuffersizeintegerEditFieldValueChanged(app, event)
            app.sbuffer_len = app.SearchbuffersizeintegerEditField.Value;
        end

        % Value changed function: 
        % LookaheadbuffersizeintegerEditField
        function LookaheadbuffersizeintegerEditFieldValueChanged(app, event)
            app.labuffer_len = app.LookaheadbuffersizeintegerEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EnterthetextfieldstringorthefilenameEditFieldLabel = uilabel(app.UIFigure);
            app.EnterthetextfieldstringorthefilenameEditFieldLabel.Text = 'Enter the text field (string) or the file name';
            app.EnterthetextfieldstringorthefilenameEditField = uieditfield(app.UIFigure, 'text');
            app.EnterthetextfieldstringorthefilenameEditField.ValueChangedFcn = createCallbackFcn(app, @EnterthetextfieldstringorthefilenameEditFieldValueChanged, true);
            app.InputsLabel = uilabel(app.UIFigure);
            app.InputsLabel.Text = 'Inputs';
            app.OutputsLabel = uilabel(app.UIFigure);
            app.OutputsLabel.Text = 'Outputs';
            app.SearchbuffersizeintegerEditFieldLabel = uilabel(app.UIFigure);
            app.SearchbuffersizeintegerEditFieldLabel.Text = 'Search buffer size (integer)';
            app.SearchbuffersizeintegerEditField = uieditfield(app.UIFigure, 'numeric');
            app.SearchbuffersizeintegerEditField.ValueChangedFcn = createCallbackFcn(app, @SearchbuffersizeintegerEditFieldValueChanged, true);
            app.LookaheadbuffersizeintegerEditFieldLabel = uilabel(app.UIFigure);
            app.LookaheadbuffersizeintegerEditFieldLabel.Text = 'Look-ahead buffer size (integer)';
            app.LookaheadbuffersizeintegerEditField = uieditfield(app.UIFigure, 'numeric');
            app.LookaheadbuffersizeintegerEditField.ValueChangedFcn = createCallbackFcn(app, @LookaheadbuffersizeintegerEditFieldValueChanged, true);
            app.BytesperinputcharacterEditFieldLabel = uilabel(app.UIFigure);
            app.BytesperinputcharacterEditFieldLabel.Text = 'Bytes per input character (text entropy) without compression';
            app.BytesperinputcharactertextentropywithoutcompressionEditField = uieditfield(app.UIFigure, 'numeric');
            app.CompresstheinputtextButton = uibutton(app.UIFigure, 'push');
            app.CompresstheinputtextButton.ButtonPushedFcn = createCallbackFcn(app, @CompresstheinputtextButtonPushed, true);
            app.CompresstheinputtextButton.Text = 'Compress the input text';
            app.LZ77encodedoutputtripletsTextAreaLabel = uilabel(app.UIFigure);
            app.LZ77encodedoutputtripletsTextAreaLabel.Text = 'LZ77 encoded output (triplets) ';
            app.LZ77encodedoutputtripletsTextArea = uitextarea(app.UIFigure);
            app.LZSSencodedoutputtuplesTextAreaLabel = uilabel(app.UIFigure);
            app.LZSSencodedoutputtuplesTextAreaLabel.Text = 'LZSS encoded output (tuples)';
            app.LZSSencodedoutputtuplesTextArea = uitextarea(app.UIFigure);
            app.CompressedbytesperinputcharacterusingLZSSEditFieldLabel = uilabel(app.UIFigure);
            app.CompressedbytesperinputcharacterusingLZSSEditFieldLabel.Text = 'Compressed bytes per input character (using LZSS)';
            app.CompressedbytesperinputcharacterusingLZSSEditField = uieditfield(app.UIFigure, 'numeric');
            app.CompressedbytesperinputcharacterusingLZ77EditField_2Label = uilabel(app.UIFigure);
            app.CompressedbytesperinputcharacterusingLZ77EditField_2Label.Text = 'Compressed bytes per input character (using LZ77)';
            app.CompressedbytesperinputcharacterusingLZ77EditField_2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hw2_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end