classdef hw3_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InputsLabel_2                   matlab.ui.control.Label
        OutputsLabel_2                  matlab.ui.control.Label
        EnterthenumberofpowersourcesintegerEditField_2Label  matlab.ui.control.Label
        EnterthenumberofpowersourcesintegerEditField_2  matlab.ui.control.NumericEditField
        EntertotalpowerconstraintdoubleEditField_2Label  matlab.ui.control.Label
        EntertotalpowerconstraintdoubleEditField_2  matlab.ui.control.NumericEditField
        EnterchannelgainscommaseperatedvectorEditField_2Label  matlab.ui.control.Label
        EnterchannelgainscommaseperatedvectorEditField_2  matlab.ui.control.EditField
        EnterchannelweightscommaseperatedvectorEditField_2Label  matlab.ui.control.Label
        EnterchannelweightscommaseperatedvectorEditField_2  matlab.ui.control.EditField
        EnterchannelnoisepowerscommaseperatedvectorsEditFieldLabel  matlab.ui.control.Label
        EnterchannelnoisepowerscommaseperatedvectorsEditField  matlab.ui.control.EditField
        SolvethewaterfillingpowerallocationButton  matlab.ui.control.Button
        OptimumweightedsumrateEditField_2Label  matlab.ui.control.Label
        OptimumweightedsumrateEditField_2  matlab.ui.control.NumericEditField
        UniformweightedsumrateEditField_2Label  matlab.ui.control.Label
        UniformweightedsumrateEditField_2  matlab.ui.control.NumericEditField
        UnifrompowerallocationEditFieldLabel  matlab.ui.control.Label
        UnifrompowerallocationEditField  matlab.ui.control.EditField
        UniformchannelratesEditField_2Label  matlab.ui.control.Label
        UniformchannelratesEditField_2  matlab.ui.control.EditField
        OptimumchannelratesEditField_2Label  matlab.ui.control.Label
        OptimumchannelratesEditField_2  matlab.ui.control.EditField
        OptimumpowerallocationEditField_2Label  matlab.ui.control.Label
        OptimumpowerallocationEditField_2  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        K;
        weights;
        A;
        P;
        N;
        gamma;
        gammaInv;
        lambda;
        roots;
        opt_p;
        opt_R;
        Rwf_sumrate;
        unif_p;
        unif_R;
        unif_Rsumrate;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: 
        % EnterthenumberofpowersourcesintegerEditField_2
        function EnterthenumberofpowersourcesintegerEditField_2ValueChanged(app, event)
            app.K = app.EnterthenumberofpowersourcesintegerEditField_2.Value;
            
        end

        % Value changed function: 
        % EntertotalpowerconstraintdoubleEditField_2
        function EntertotalpowerconstraintdoubleEditField_2ValueChanged(app, event)
            app.P = app.EntertotalpowerconstraintdoubleEditField_2.Value;            
        end

        % Value changed function: 
        % EnterchannelweightscommaseperatedvectorEditField_2
        function EnterchannelweightscommaseperatedvectorEditField_2ValueChanged(app, event)
            wStr = app.EnterchannelweightscommaseperatedvectorEditField_2.Value;
            app.weights = str2double(strsplit(wStr,','));
        end

        % Value changed function: 
        % EnterchannelgainscommaseperatedvectorEditField_2
        function EnterchannelgainscommaseperatedvectorEditField_2ValueChanged(app, event)
            AStr = app.EnterchannelgainscommaseperatedvectorEditField_2.Value;
            app.A = str2double(strsplit(AStr,','));
        end

        % Value changed function: 
        % EnterchannelnoisepowerscommaseperatedvectorsEditField
        function EnterchannelnoisepowerscommaseperatedvectorsEditFieldValueChanged(app, event)
            NStr = app.EnterchannelnoisepowerscommaseperatedvectorsEditField.Value;
            app.N = str2double(strsplit(NStr,','));
        end

        % Button pushed function: 
        % SolvethewaterfillingpowerallocationButton
        function SolvethewaterfillingpowerallocationButtonPushed(app, event)
            app.gamma = (app.A.^2)./(app.N);
            app.gammaInv = 1./(app.gamma);
            app.roots = (app.gammaInv)./(app.weights);
            [app.roots,idx] = sort(app.roots);
            app.roots = [app.roots, inf];
            for i=1:app.K
                r1 = app.roots(i);
                r2 = app.roots(i+1);
                app.lambda = (sum(app.gammaInv(idx(1:i)))+app.P)/sum(app.weights(idx(1:i)));  
                fprintf('Segment considered: (%f,%f)',r1,r2)
                fprintf('Candidate solution: %f\n',app.lambda)
                if r1<=app.lambda && app.lambda<=r2
                    break;
                end
            end
            app.opt_p = max(0,app.weights*app.lambda-app.gammaInv);
            app.opt_R = log2(1+app.gamma.*app.opt_p);
            app.Rwf_sumrate = sum(app.weights.*app.opt_R);
            app.unif_p = app.P/app.K*ones(1,app.K);
            app.unif_R = log2(1+app.gamma.*app.unif_p);
            app.unif_Rsumrate = sum(app.weights.*app.unif_R);
            app.OptimumpowerallocationEditField_2.Value=mat2str(round(app.opt_p,2));
            app.OptimumchannelratesEditField_2.Value=mat2str(round(app.opt_R,2));
            app.OptimumweightedsumrateEditField_2.Value=round(app.Rwf_sumrate,2);
            app.UnifrompowerallocationEditField.Value=mat2str(round(app.unif_p,2));
            app.UniformchannelratesEditField_2.Value=mat2str(round(app.unif_R,2));
            app.UniformweightedsumrateEditField_2.Value=round(app.unif_Rsumrate,2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InputsLabel_2 = uilabel(app.UIFigure);
            app.InputsLabel_2.Text = 'Inputs';
            app.OutputsLabel_2 = uilabel(app.UIFigure);
            app.OutputsLabel_2.Text = 'Outputs';
            app.EnterthenumberofpowersourcesintegerEditField_2Label = uilabel(app.UIFigure);
            app.EnterthenumberofpowersourcesintegerEditField_2Label.Text = 'Enter the number of power sources (integer)';
            app.EnterthenumberofpowersourcesintegerEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EnterthenumberofpowersourcesintegerEditField_2.ValueChangedFcn = createCallbackFcn(app, @EnterthenumberofpowersourcesintegerEditField_2ValueChanged, true);
            app.EntertotalpowerconstraintdoubleEditField_2Label = uilabel(app.UIFigure);
            app.EntertotalpowerconstraintdoubleEditField_2Label.Text = 'Enter total power constraint (double)';
            app.EntertotalpowerconstraintdoubleEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EntertotalpowerconstraintdoubleEditField_2.ValueChangedFcn = createCallbackFcn(app, @EntertotalpowerconstraintdoubleEditField_2ValueChanged, true);
            app.EnterchannelgainscommaseperatedvectorEditField_2Label = uilabel(app.UIFigure);
            app.EnterchannelgainscommaseperatedvectorEditField_2Label.Text = 'Enter channel gains (comma-seperated vector)';
            app.EnterchannelgainscommaseperatedvectorEditField_2 = uieditfield(app.UIFigure, 'text');
            app.EnterchannelgainscommaseperatedvectorEditField_2.ValueChangedFcn = createCallbackFcn(app, @EnterchannelgainscommaseperatedvectorEditField_2ValueChanged, true);
            app.EnterchannelweightscommaseperatedvectorEditField_2Label = uilabel(app.UIFigure);
            app.EnterchannelweightscommaseperatedvectorEditField_2Label.Text = 'Enter channel weights (comma-seperated vector)';
            app.EnterchannelweightscommaseperatedvectorEditField_2 = uieditfield(app.UIFigure, 'text');
            app.EnterchannelweightscommaseperatedvectorEditField_2.ValueChangedFcn = createCallbackFcn(app, @EnterchannelweightscommaseperatedvectorEditField_2ValueChanged, true);
            app.EnterchannelnoisepowerscommaseperatedvectorsEditFieldLabel = uilabel(app.UIFigure);
            app.EnterchannelnoisepowerscommaseperatedvectorsEditFieldLabel.Text = 'Enter channel noise powers (comma-seperated vectors)';
            app.EnterchannelnoisepowerscommaseperatedvectorsEditField = uieditfield(app.UIFigure, 'text');
            app.EnterchannelnoisepowerscommaseperatedvectorsEditField.ValueChangedFcn = createCallbackFcn(app, @EnterchannelnoisepowerscommaseperatedvectorsEditFieldValueChanged, true);
            app.SolvethewaterfillingpowerallocationButton = uibutton(app.UIFigure, 'push');
            app.SolvethewaterfillingpowerallocationButton.ButtonPushedFcn = createCallbackFcn(app, @SolvethewaterfillingpowerallocationButtonPushed, true);
            app.SolvethewaterfillingpowerallocationButton.Text = 'Solve the water-filling power allocation';
            app.OptimumweightedsumrateEditField_2Label = uilabel(app.UIFigure);
            app.OptimumweightedsumrateEditField_2Label.Text = 'Optimum weighted sum rate';
            app.OptimumweightedsumrateEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.UniformweightedsumrateEditField_2Label = uilabel(app.UIFigure);
            app.UniformweightedsumrateEditField_2Label.Text = 'Uniform weighted sum rate';
            app.UniformweightedsumrateEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.UnifrompowerallocationEditFieldLabel = uilabel(app.UIFigure);
            app.UnifrompowerallocationEditFieldLabel.Text = 'Unifrom power allocation';
            app.UnifrompowerallocationEditField = uieditfield(app.UIFigure, 'text');
            app.UniformchannelratesEditField_2Label = uilabel(app.UIFigure);
            app.UniformchannelratesEditField_2Label.Text = 'Uniform channel rates';
            app.UniformchannelratesEditField_2 = uieditfield(app.UIFigure, 'text');
            app.OptimumchannelratesEditField_2Label = uilabel(app.UIFigure);
            app.OptimumchannelratesEditField_2Label.Text = 'Optimum channel rates';
            app.OptimumchannelratesEditField_2 = uieditfield(app.UIFigure, 'text');
            app.OptimumpowerallocationEditField_2Label = uilabel(app.UIFigure);
            app.OptimumpowerallocationEditField_2Label.Text = 'Optimum power allocation';
            app.OptimumpowerallocationEditField_2 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hw3_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end