classdef temp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatientsDisplayUIFigure       matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        InputsLabel                   matlab.ui.control.Label
        EntertotalpowerconstraintdoubleEditFieldLabel  matlab.ui.control.Label
        EntertotalpowerconstraintdoubleEditField  matlab.ui.control.NumericEditField
        EnterThenumberofpowersourcesintegerEditFieldLabel  matlab.ui.control.Label
        EnterThenumberofpowersourcesintegerEditField  matlab.ui.control.NumericEditField
        EnterchannelgainscommaseperatedvectorLabel  matlab.ui.control.Label
        EnterchannelgainscommaseperatedvectorEditField  matlab.ui.control.NumericEditField
        EnterchannelweightscommaseperatedvectorEditFieldLabel  matlab.ui.control.Label
        EnterchannelweightscommaseperatedvectorEditField  matlab.ui.control.NumericEditField
        SolvethewaterfillingpowerallocationproblemButton  matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        WaterfillingsolutionlambdaEditFieldLabel  matlab.ui.control.Label
        WaterfillingsolutionlambdaEditField  matlab.ui.control.NumericEditField
        OptimumpowerdistributionEditFieldLabel  matlab.ui.control.Label
        OptimumpowerdistributionEditField  matlab.ui.control.NumericEditField
        OptimumweightedsumrateEditFieldLabel  matlab.ui.control.Label
        OptimumweightedsumrateEditField  matlab.ui.control.NumericEditField
        UniformweightedsumrateEditFieldLabel  matlab.ui.control.Label
        UniformweightedsumrateEditField  matlab.ui.control.NumericEditField
        UniformpowerdistributionEditFieldLabel  matlab.ui.control.Label
        UniformpowerdistributionEditField  matlab.ui.control.NumericEditField
        OptimumchannelratesEditFieldLabel  matlab.ui.control.Label
        OptimumchannelratesEditField  matlab.ui.control.NumericEditField
        UniformchannelratesEditFieldLabel  matlab.ui.control.Label
        UniformchannelratesEditField  matlab.ui.control.NumericEditField
        OutputsLabel                  matlab.ui.control.Label
        LambdaintervalcandidatesEditFieldLabel  matlab.ui.control.Label
        LambdaintervalcandidatesEditField  matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % The app displays the data by using the scatter plot, histogram, and table.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch, dropdown, and radiobutton group.
    % The data used in the app is shiped with the product.
    
    properties (Access = private)
        % Declare properties of the PatientsDisplay class.
        Data
        SelectedGenders
        SelectedColors
        BinWidth
        Histogram = gobjects(0)
        displayedIndices
    end
    
    methods (Access = private)
        
        function NBins = numhistbins(app,data)
            % Utility function to compute the number of histogram bins
            binwidth = app.BinWidth;
            range2plot =  floor(min(data)):binwidth:ceil(max(data));
            NBins = size(range2plot,2);
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the data.
            load('patients.mat','LastName','Gender','Smoker','Age','Height','Weight','Diastolic','Systolic','Location');
            
            % Store the data in a table and display it in one of the App's tabs.
            app.Data = table(LastName,Gender,Smoker,Age,Height,Weight,Diastolic,Systolic,Location);
            app.UITable.Data = app.Data;
            app.BinWidth = app.BinWidthSlider.Value;
            
            % Update the axes with the corresponding data.
            updateSelectedGenders(app)
            refreshplot(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PatientsDisplayUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {400, 400};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {412, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function SliderValueChanging(app, event)
            % Update the histogram as the slider value for bindwidth changes.
            app.BinWidth = event.Value;
            for ii=1:length(app.Histogram)
                app.Histogram(ii).NumBins = numhistbins(app,app.Histogram(ii).Data);
            end
        end

        % Callback function
        function refreshplot(app, event)
            Genders = app.SelectedGenders;
            Colors = app.SelectedColors;
            
            % Start with a fresh plot
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            app.Histogram = gobjects(0);
            
            % Select relevant segment of data
            xdata = app.Data.Weight;
            ydata = app.Data.(app.BloodPressureSwitch.Value);
            
            % Filter the data according to the controls
            filterData(app);
            
            % Create either a scatter plot or histogram, based on selection
            switch app.ButtonGroup.SelectedObject.Text
                
                case 'Scatter'
                    % Build a scatter plot for each selected gender
                    for ii = 1:length(Genders)
                        selectedpatients = ((app.Data.Gender == Genders(ii)) & (app.displayedIndices));
                        scatter(app.UIAxes,xdata((selectedpatients)),ydata(selectedpatients),Colors{ii});
                    end
                    annotateScatterPlot(app)
                    
                case 'Histogram'
                    % Build a histogram for each selected gender
                    for ii = 1:length(Genders)
                        selectedpatients = ((app.Data.Gender == Genders(ii)) & (app.displayedIndices));
                        NBins = numhistbins(app,ydata(selectedpatients));
                        h = histogram(app.UIAxes,ydata(selectedpatients),NBins,'BinLimits',[floor(min(ydata)) ceil(max(ydata))]);
                        h.EdgeColor = Colors{ii};
                        h.FaceColor = Colors{ii};
                        app.Histogram = [app.Histogram h];
                    end
                    annotateHistogram(app)
                    
            end
            
            % Update the table to show only the data that satisfies the controls
            app.UITable.Data = app.Data(app.displayedIndices,:);
            drawnow;
        end

        % Callback function
        function updateSelectedGenders(app, event)

            
            if app.MaleCheckBox.Value
                Genders = "Male";
                Colors = "blue";
            end
            if app.FemaleCheckBox.Value
                Genders = [Genders "Female"];
                Colors = [Colors "red"];
            end
            if app.YesCheckBox.Value
                Smoker = "Yes";
            end
            if app.NoCheckBox.Value
                Smoker = [Smoker "No"];
            end
            
            if isempty(Genders) || isempty(Smoker)
                % Disable the switches and buttons if they were on
                app.BloodPressureSwitch.Enable = 'off';
                app.ScatterButton.Enable = 'off';
                app.HistogramButton.Enable = 'off';
                app.BinWidthSliderLabel.Enable = 'off';
                app.BinWidthSlider.Enable = 'off';
            else
                % Enable the switches and buttons if they were off
                app.BloodPressureSwitch.Enable = 'on';
                app.ScatterButton.Enable = 'on';
                app.HistogramButton.Enable = 'on';
                app.BinWidthSliderLabel.Enable = 'on';
                app.BinWidthSlider.Enable = 'on';
            end
            app.SelectedGenders = Genders;
            app.SelectedColors = Colors;
            
            refreshplot(app)
        end

        % Value changed function: 
        % EntertotalpowerconstraintdoubleEditField
        function EntertotalpowerconstraintdoubleEditFieldValueChanged(app, event)
            value = app.EntertotalpowerconstraintdoubleEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatientsDisplayUIFigure = uifigure('Visible', 'off');
            app.PatientsDisplayUIFigure.Name = 'Patients Display';
            app.PatientsDisplayUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PatientsDisplayUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InputsLabel = uilabel(app.LeftPanel);
            app.InputsLabel.Text = 'Inputs';
            app.EntertotalpowerconstraintdoubleEditFieldLabel = uilabel(app.LeftPanel);
            app.EntertotalpowerconstraintdoubleEditFieldLabel.Text = 'Enter total power constraint (double)';
            app.EntertotalpowerconstraintdoubleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EntertotalpowerconstraintdoubleEditField.ValueChangedFcn = createCallbackFcn(app, @EntertotalpowerconstraintdoubleEditFieldValueChanged, true);
            app.EnterThenumberofpowersourcesintegerEditFieldLabel = uilabel(app.LeftPanel);
            app.EnterThenumberofpowersourcesintegerEditFieldLabel.Text = 'Enter The number of power sources (integer) ';
            app.EnterThenumberofpowersourcesintegerEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EnterchannelgainscommaseperatedvectorLabel = uilabel(app.LeftPanel);
            app.EnterchannelgainscommaseperatedvectorLabel.Text = 'Enter channel gains (comma-seperated vector)';
            app.EnterchannelgainscommaseperatedvectorEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EnterchannelweightscommaseperatedvectorEditFieldLabel = uilabel(app.LeftPanel);
            app.EnterchannelweightscommaseperatedvectorEditFieldLabel.Text = 'Enter channel weights (comma-seperated vector)';
            app.EnterchannelweightscommaseperatedvectorEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SolvethewaterfillingpowerallocationproblemButton = uibutton(app.LeftPanel, 'push');
            app.SolvethewaterfillingpowerallocationproblemButton.Text = 'Solve the water-filling power allocation problem ';
            app.RightPanel = uipanel(app.GridLayout);
            app.WaterfillingsolutionlambdaEditFieldLabel = uilabel(app.RightPanel);
            app.WaterfillingsolutionlambdaEditFieldLabel.Text = 'Water-filling solution (lambda)';
            app.WaterfillingsolutionlambdaEditField = uieditfield(app.RightPanel, 'numeric');
            app.OptimumpowerdistributionEditFieldLabel = uilabel(app.RightPanel);
            app.OptimumpowerdistributionEditFieldLabel.Text = 'Optimum power distribution ';
            app.OptimumpowerdistributionEditField = uieditfield(app.RightPanel, 'numeric');
            app.OptimumweightedsumrateEditFieldLabel = uilabel(app.RightPanel);
            app.OptimumweightedsumrateEditFieldLabel.Text = 'Optimum weighted sum rate';
            app.OptimumweightedsumrateEditField = uieditfield(app.RightPanel, 'numeric');
            app.UniformweightedsumrateEditFieldLabel = uilabel(app.RightPanel);
            app.UniformweightedsumrateEditFieldLabel.Text = 'Uniform weighted sum rate';
            app.UniformweightedsumrateEditField = uieditfield(app.RightPanel, 'numeric');
            app.UniformpowerdistributionEditFieldLabel = uilabel(app.RightPanel);
            app.UniformpowerdistributionEditFieldLabel.Text = 'Uniform power distribution ';
            app.UniformpowerdistributionEditField = uieditfield(app.RightPanel, 'numeric');
            app.OptimumchannelratesEditFieldLabel = uilabel(app.RightPanel);
            app.OptimumchannelratesEditFieldLabel.Text = 'Optimum channel rates';
            app.OptimumchannelratesEditField = uieditfield(app.RightPanel, 'numeric');
            app.UniformchannelratesEditFieldLabel = uilabel(app.RightPanel);
            app.UniformchannelratesEditFieldLabel.Text = 'Uniform channel rates';
            app.UniformchannelratesEditField = uieditfield(app.RightPanel, 'numeric');
            app.OutputsLabel = uilabel(app.RightPanel);
            app.OutputsLabel.Text = 'Outputs';
            app.LambdaintervalcandidatesEditFieldLabel = uilabel(app.RightPanel);
            app.LambdaintervalcandidatesEditFieldLabel.Text = 'Lambda interval candidates';
            app.LambdaintervalcandidatesEditField = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.PatientsDisplayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = temp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatientsDisplayUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatientsDisplayUIFigure)
        end
    end
end